classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        zadanaEditField       matlab.ui.control.NumericEditField
        zadanaEditFieldLabel  matlab.ui.control.Label
        Switch                matlab.ui.control.Switch
        ONLINELamp            matlab.ui.control.Lamp
        ONLINELampLabel       matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        NASTAWYLabel          matlab.ui.control.Label
        TrybDropDown          matlab.ui.control.DropDown
        TrybDropDownLabel     matlab.ui.control.Label
        suwakSlider           matlab.ui.control.Slider
        suwakSliderLabel      matlab.ui.control.Label
        STEROWANIELabel       matlab.ui.control.Label
        ograniczoneButton     matlab.ui.control.Button
        nieograniczoneButton  matlab.ui.control.Button
        DEditField_4          matlab.ui.control.NumericEditField
        DEditField_4Label     matlab.ui.control.Label
        GEditField_4          matlab.ui.control.NumericEditField
        GEditField_4Label     matlab.ui.control.Label
        PEditField_4          matlab.ui.control.NumericEditField
        PEditField_4Label     matlab.ui.control.Label
        LEditField_4          matlab.ui.control.NumericEditField
        LEditField_4Label     matlab.ui.control.Label
        DEditField_3          matlab.ui.control.NumericEditField
        DEditField_3Label     matlab.ui.control.Label
        GEditField_3          matlab.ui.control.NumericEditField
        GEditField_3Label     matlab.ui.control.Label
        PEditField_3          matlab.ui.control.NumericEditField
        PEditField_3Label     matlab.ui.control.Label
        LEditField_3          matlab.ui.control.NumericEditField
        LEditField_3Label     matlab.ui.control.Label
        DEditField_2          matlab.ui.control.NumericEditField
        DEditField_2Label     matlab.ui.control.Label
        GEditField_2          matlab.ui.control.NumericEditField
        GEditField_2Label     matlab.ui.control.Label
        PEditField_2          matlab.ui.control.NumericEditField
        PEditField_2Label     matlab.ui.control.Label
        LEditField_2          matlab.ui.control.NumericEditField
        LEditField_2Label     matlab.ui.control.Label
        DEditField            matlab.ui.control.NumericEditField
        DEditFieldLabel       matlab.ui.control.Label
        GEditField            matlab.ui.control.NumericEditField
        GEditFieldLabel       matlab.ui.control.Label
        PEditField            matlab.ui.control.NumericEditField
        PEditFieldLabel       matlab.ui.control.Label
        LEditField            matlab.ui.control.NumericEditField
        LEditFieldLabel       matlab.ui.control.Label
        StartButton           matlab.ui.control.Button
        TpEditField           matlab.ui.control.NumericEditField
        TpEditFieldLabel      matlab.ui.control.Label
        TdEditField           matlab.ui.control.NumericEditField
        TdEditFieldLabel      matlab.ui.control.Label
        TiEditField           matlab.ui.control.NumericEditField
        TiEditFieldLabel      matlab.ui.control.Label
        kpEditField           matlab.ui.control.NumericEditField
        kpEditFieldLabel      matlab.ui.control.Label
        UIAxes6               matlab.ui.control.UIAxes
        UIAxes5               matlab.ui.control.UIAxes
        UIAxes4               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.suwakSlider.Visible = false;
            app.suwakSliderLabel.Visible = false;
            app.NASTAWYLabel.Visible = false;
            app.Label.Visible = false;
            app.ONLINELamp.Enable = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            kp= app.kpEditField.Value;
            Td= app.TdEditField.Value;
            Ti = app.TiEditField.Value;
            Tp = app.TpEditField.Value;
            N = 4500;
            w = ones(1, N);
            y = zeros(1, N);
            e = zeros(1, N);
            u = zeros(1, N);
            ISE = zeros(1, N);
            IAE = zeros(1, N);
            y(1) = 0;
            y(2) = 0;
            e(1) = 1;
            e(2) = 1;
            sumaUchybow = e(1) + e(2);
            ISE(1) = Tp*1;
            ISE(2) = Tp*1;
            IAE(1) = Tp*2;
            IAE(2) = Tp*2;
            max = 200;
            min = -max;
            for i=3:N
                e(i) = w(i) - y(i-1);
                sumaUchybow = sumaUchybow + e(i);
                %równanie (6)
                u(i) = kp*((e(i) + Tp/Ti*sumaUchybow + Td/Tp*(e(i)-e(i-1))));
                if u(i) < min
                    u(i) = min;
                elseif u(i) > max
                    u(i) = max;
                end
                %wyznaczone równanie rekursywne
                y(i) = (2-1.42*Tp)*y(i-1) + (-0.21*Tp^2+1.42*Tp-1)*y(i-2) + 1.27* Tp^2*u(i-2);
                %wskaźniki jakości
                ISE(i) = ISE(i-1) + Tp*e(i)^2;
                IAE(i) = IAE(i-1) + Tp*abs(e(i));
            end
            
            t = 0:0.01:44.99;
            plot(app.UIAxes, t, y);
            plot(app.UIAxes2, t, e);
            plot(app.UIAxes3, t, u);
            plot(app.UIAxes4, t, ISE, t, IAE);
            legend(app.UIAxes4, 'ISE', 'IAE');  
            app.NASTAWYLabel.Visible = false;
            app.Label.Visible = false;
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value = app.LEditField.Value;
            app.UIAxes.XLim= [value app.UIAxes.XLim(2)];
        end

        % Value changed function: LEditField_2
        function LEditField_2ValueChanged(app, event)
            value = app.LEditField_2.Value;
            app.UIAxes3.XLim= [value app.UIAxes3.XLim(2)];
        end

        % Value changed function: LEditField_3
        function LEditField_3ValueChanged(app, event)
            value = app.LEditField_3.Value;
            app.UIAxes2.XLim= [value app.UIAxes2.XLim(2)];
        end

        % Value changed function: LEditField_4
        function LEditField_4ValueChanged(app, event)
            value = app.LEditField_4.Value;
            app.UIAxes4.XLim= [value app.UIAxes4.XLim(2)];
        end

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            value = app.PEditField.Value;
            app.UIAxes.XLim= [app.UIAxes.XLim(1) value];
        end

        % Value changed function: PEditField_2
        function PEditField_2ValueChanged(app, event)
            value = app.PEditField_2.Value;
            app.UIAxes3.XLim= [app.UIAxes3.XLim(1) value];
        end

        % Value changed function: PEditField_3
        function PEditField_3ValueChanged(app, event)
            value = app.PEditField_3.Value;
            app.UIAxes2.XLim= [app.UIAxes2.XLim(1) value];
        end

        % Value changed function: PEditField_4
        function PEditField_4ValueChanged(app, event)
            value = app.PEditField_4.Value;
            app.UIAxes4.XLim= [app.UIAxes4.XLim(1) value];
        end

        % Value changed function: GEditField
        function GEditFieldValueChanged(app, event)
            value = app.GEditField.Value;
            app.UIAxes.YLim = [app.UIAxes.YLim(1) value];
        end

        % Value changed function: GEditField_2
        function GEditField_2ValueChanged(app, event)
            value = app.GEditField_2.Value;
            app.UIAxes3.YLim = [app.UIAxes3.YLim(1) value];
        end

        % Value changed function: GEditField_3
        function GEditField_3ValueChanged(app, event)
            value = app.GEditField_3.Value;
            app.UIAxes2.YLim = [app.UIAxes2.YLim(1) value];
        end

        % Value changed function: GEditField_4
        function GEditField_4ValueChanged(app, event)
            value = app.GEditField_4.Value;
            app.UIAxes4.YLim = [app.UIAxes4.YLim(1) value];
        end

        % Value changed function: DEditField_4
        function DEditField_4ValueChanged(app, event)
            value = app.DEditField_4.Value;
            app.UIAxes4.YLim = [value app.UIAxes4.YLim(2)];
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            value = app.DEditField.Value;
            app.UIAxes.YLim = [value app.UIAxes.YLim(2)];
        end

        % Value changed function: DEditField_2
        function DEditField_2ValueChanged(app, event)
            value = app.DEditField_2.Value;
            app.UIAxes3.YLim = [value app.UIAxes3.YLim(2)];
        end

        % Value changed function: DEditField_3
        function DEditField_3ValueChanged(app, event)
            value = app.DEditField_3.Value;
            app.UIAxes2.YLim = [value app.UIAxes2.YLim(2)];
        end

        % Button pushed function: nieograniczoneButton
        function nieograniczoneButtonPushed(app, event)
            app.suwakSlider.Visible = false;
            app.suwakSliderLabel.Visible = false;
        end

        % Button pushed function: ograniczoneButton
        function ograniczoneButtonPushed(app, event)
            app.suwakSlider.Visible = true;
            app.suwakSliderLabel.Visible = true;
        end

        % Value changed function: suwakSlider
        function suwakSliderValueChanged(app, event)
            value = app.suwakSlider.Value;
            max = value;
            min = -max;
            kp= app.kpEditField.Value;
            Td= app.TdEditField.Value;
            Ti = app.TiEditField.Value;
            Tp = app.TpEditField.Value;
            N = 4500;
            w = ones(1, N);
            y = zeros(1, N);
            e = zeros(1, N);
            u = zeros(1, N);
            ISE = zeros(1, N);
            IAE = zeros(1, N);
            y(1) = 0;
            y(2) = 0;
            e(1) = 1;
            e(2) = 1;
            sumaUchybow = e(1) + e(2);
            ISE(1) = Tp*1;
            ISE(2) = Tp*1;
            IAE(1) = Tp*2;
            IAE(2) = Tp*2;
            for i=3:N
                e(i) = w(i) - y(i-1);
                sumaUchybow = sumaUchybow + e(i);
                %równanie (6)
                u(i) = kp*((e(i) + Tp/Ti*sumaUchybow + Td/Tp*(e(i)-e(i-1))));
                if u(i) < min
                    u(i) = min;
                elseif u(i) > max
                    u(i) = max;
                end
                %wyznaczone równanie rekursywne
                y(i) = (2-1.42*Tp)*y(i-1) + (-0.21*Tp^2+1.42*Tp-1)*y(i-2) + 1.27* Tp^2*u(i-2);
                %wskaźniki jakości
                ISE(i) = ISE(i-1) + Tp*e(i)^2;
                IAE(i) = IAE(i-1) + Tp*abs(e(i));
            end
            
            t = 0:0.01:44.99;
            plot(app.UIAxes, t, y);
            plot(app.UIAxes2, t, e);
            plot(app.UIAxes3, t, u);
            plot(app.UIAxes4, t, ISE, t, IAE);
            legend(app.UIAxes4, 'ISE', 'IAE');
            app.Label.Visible = false;
        end

        % Value changed function: TrybDropDown
        function TrybDropDownValueChanged(app, event)
            value = app.TrybDropDown.Value;
            if value == "modułu"
                k = 6;
                T_1 = 5.9;
                T_2 = 0.8;
                s = tf('s');
                G_s = k/((1+s*T_1)*(1+s*T_2));
                kp = 0.615;
                Ti = 5.9;
                Td = 0.1;
                Tp = 0.01;
                N = 4500;
                w = ones(1, N);
                y = zeros(1, N);
                e = zeros(1, N);
                u = zeros(1, N);
                ISE = zeros(1, N);
                IAE = zeros(1, N);
                e(1) = 1;
                e(2) = 1;
                sumaUchybow = e(1) + e(2);
                ISE(1) = Tp*1;
                ISE(2) = Tp*1;
                IAE(1) = Tp*2;
                IAE(2) = Tp*2;
                for i=3:N
                    e(i) = w(i) - y(i-1);
                    sumaUchybow = sumaUchybow + e(i);
                    u(i) = kp*((e(i) + Tp/Ti*sumaUchybow + Td/Tp*(e(i)-e(i-1))));
                    y(i) = (2-1.42*Tp)*y(i-1) + (-0.21*Tp^2+1.42*Tp-1)*y(i-2) + 1.27* Tp^2*u(i-2);
                    ISE(i) = ISE(i-1) + Tp*e(i)^2;
                    IAE(i) = IAE(i-1) + Tp*abs(e(i));
                end
                t = 0:0.01:44.99;
                plot(app.UIAxes, t, y);
                plot(app.UIAxes2, t, e);
                plot(app.UIAxes3, t, u);
                plot(app.UIAxes4, t, ISE, t, IAE);
                legend(app.UIAxes4, 'ISE', 'IAE');
                app.kpEditField.Value = 0.615;
                app.TiEditField.Value = 5.9;
                app.TdEditField.Value = 0.1;
                app.TpEditField.Value = 0.01;
                app.NASTAWYLabel.Visible = true;
                app.Label.Visible = true;
                app.Label.Text = "na wejściu skok jednostkowy";
            elseif value == "symetrii"
                k = 6;
                T_1 = 5.9;
                T_2 = 0.8;
                s = tf('s');
                G_s = k/((1+s*T_1)*(1+s*T_2));
                kp = 0.615;
                Ti = 3.2;
                Td = 0.1;
                Tp = 0.01;
                N = 4500;
                w = ones(1, N);
                y = zeros(1, N);
                e = zeros(1, N);
                u = zeros(1, N);
                ISE = zeros(1, N);
                IAE = zeros(1, N);
                e(1) = 1;
                e(2) = 1;
                sumaUchybow = e(1) + e(2);
                ISE(1) = Tp*1;
                ISE(2) = Tp*1;
                IAE(1) = Tp*2;
                IAE(2) = Tp*2;
                for i=3:N
                    e(i) = w(i) - y(i-1);
                    sumaUchybow = sumaUchybow + e(i);
                    u(i) = kp*((e(i) + Tp/Ti*sumaUchybow + Td/Tp*(e(i)-e(i-1))));
                    y(i) = (2-1.42*Tp)*y(i-1) + (-0.21*Tp^2+1.42*Tp-1)*y(i-2) + 1.27* Tp^2*u(i-2);
                    ISE(i) = ISE(i-1) + Tp*e(i)^2;
                    IAE(i) = IAE(i-1) + Tp*abs(e(i));
                end
                t = 0:0.01:44.99;
                plot(app.UIAxes, t, y);
                plot(app.UIAxes2, t, e);
                plot(app.UIAxes3, t, u);
                plot(app.UIAxes4, t, ISE, t, IAE);
                legend(app.UIAxes4, 'ISE', 'IAE');
                app.kpEditField.Value = 0.615;
                app.TiEditField.Value = 3.2;
                app.TdEditField.Value = 0.1;
                app.TpEditField.Value = 0.01;
                app.NASTAWYLabel.Visible = true;
                app.Label.Visible = true;
                app.Label.Text = "na wejściu skok jednostkowy";
            elseif value == "wyprzedzające"
                k = 6;
                T_1 = 5.9;
                T_2 = 0.8;
                s = tf('s');
                G_s = k/((1+s*T_1)*(1+s*T_2));
                kp = 1.6;
                Ti = 6;
                Td = 0.08;
                Tp = 0.01;
                N = 4500;
                t = 0:0.01:44.99;
                w = sin(2*t);
                y = zeros(1, N);
                e = zeros(1, N);
                u = zeros(1, N);
                ISE = zeros(1, N);
                IAE = zeros(1, N);
                e(1) = 1;
                e(2) = 1;
                sumaUchybow = e(1) + e(2);
                ISE(1) = Tp*1;
                ISE(2) = Tp*1;
                IAE(1) = Tp*2;
                IAE(2) = Tp*2;
                for i=3:N
                    e(i) = w(i) - y(i-1);
                    sumaUchybow = sumaUchybow + e(i);
                    u(i) = kp*((e(i) + Tp/Ti*sumaUchybow + Td/Tp*(e(i)-e(i-1))));
                    uf = 4.72/6*(-4)*w(i)+6.7/6*2*cos(2*t(i))+1/6*w(i);
                    u(i) = uf + u(i);
                    y(i) = (2-1.42*Tp)*y(i-1) + (-0.21*Tp^2+1.42*Tp-1)*y(i-2) + 1.27* Tp^2*u(i-2);
                    ISE(i) = ISE(i-1) + Tp*e(i)^2;
                    IAE(i) = IAE(i-1) + Tp*abs(e(i));
                end
                plot(app.UIAxes, t, y);
                plot(app.UIAxes2, t, e);
                plot(app.UIAxes3, t, u);
                plot(app.UIAxes4, t, ISE, t, IAE);
                legend(app.UIAxes4, 'ISE', 'IAE');
                app.kpEditField.Value = 1.6;
                app.TiEditField.Value = 6;
                app.TdEditField.Value = 0.08;
                app.TpEditField.Value = 0.01;
                app.NASTAWYLabel.Visible = true;
                app.Label.Visible = true;
                app.Label.Text = "na wejściu sin(2*t)";
            elseif value == "ogr. z AWC"
                k = 6;
                T_1 = 5.9;
                T_2 = 0.8;
                s = tf('s');
                G_s = k/((1+s*T_1)*(1+s*T_2));
                kp = 1.6;
                Ti = 6;
                Td = 0.08;
                Tp = 0.01;
                N = 4500;
                t = 0:0.01:44.99;
                w = square(t, 50);
                y = zeros(1, N);
                e = zeros(1, N);
                u = zeros(1, N);
                ISE = zeros(1, N);
                IAE = zeros(1, N);
                e(1) = 1;
                e(2) = 1;
                sumaUchybow = e(1) + e(2);
                ISE(1) = Tp*1;
                ISE(2) = Tp*1;
                IAE(1) = Tp*2;
                IAE(2) = Tp*2;
                max = 1.3;
                min = -max;
                for i=3:N
                    e(i) = w(i) - y(i-1);
                    sumaUchybow = sumaUchybow + e(i);
                    u(i) = kp*((e(i) + Tp/Ti*sumaUchybow + Td/Tp*(e(i)-e(i-1))));
                    if abs(e(i)) > 0.02
                        u(i) = u(i) - kp*Tp/Ti*sumaUchybow;
                    end
                    if u(i) < min
                        u(i) = min;
                    elseif u(i) > max
                        u(i) = max;
                    end
                    y(i) = (2-1.42*Tp)*y(i-1) + (-0.21*Tp^2+1.42*Tp-1)*y(i-2) + 1.27* Tp^2*u(i-2);
                    ISE(i) = ISE(i-1) + Tp*e(i)^2;
                    IAE(i) = IAE(i-1) + Tp*abs(e(i));
                end
                plot(app.UIAxes, t, y);
                plot(app.UIAxes2, t, e);
                plot(app.UIAxes3, t, u);
                plot(app.UIAxes4, t, ISE, t, IAE);
                legend(app.UIAxes4, 'ISE', 'IAE');
                app.kpEditField.Value = 1.6;
                app.TiEditField.Value = 6;
                app.TdEditField.Value = 0.08;
                app.TpEditField.Value = 0.01;
                app.NASTAWYLabel.Visible = true;
                app.Label.Visible = true;
                app.Label.Text = "na wejściu prostokąt (50%)";
            elseif value == "samodzielnie"
                k = 6;
                T_1 = 5.9;
                T_2 = 0.8;
                s = tf('s');
                G_s = k/((1+s*T_1)*(1+s*T_2));
                kp = 1.6;
                Ti = 6;
                Td = 0.08;
                Tp = 0.01;
                N = 4500;
                w = ones(1, N);
                y = zeros(1, N);
                e = zeros(1, N);
                u = zeros(1, N);
                ISE = zeros(1, N);
                IAE = zeros(1, N);
                e(1) = 1;
                e(2) = 1;
                sumaUchybow = e(1) + e(2);
                ISE(1) = Tp*1;
                ISE(2) = Tp*1;
                IAE(1) = Tp*2;
                IAE(2) = Tp*2;
                for i=3:N
                    e(i) = w(i) - y(i-1);
                    sumaUchybow = sumaUchybow + e(i);
                    u(i) = kp*((e(i) + Tp/Ti*sumaUchybow + Td/Tp*(e(i)-e(i-1))));
                    y(i) = (2-1.42*Tp)*y(i-1) + (-0.21*Tp^2+1.42*Tp-1)*y(i-2) + 1.27* Tp^2*u(i-2);
                    ISE(i) = ISE(i-1) + Tp*e(i)^2;
                    IAE(i) = IAE(i-1) + Tp*abs(e(i));
                end
                t = 0:0.01:44.99;
                plot(app.UIAxes, t, y);
                plot(app.UIAxes2, t, e);
                plot(app.UIAxes3, t, u);
                plot(app.UIAxes4, t, ISE, t, IAE);
                legend(app.UIAxes4, 'ISE', 'IAE');
                app.kpEditField.Value = 1.6;
                app.TiEditField.Value = 6;
                app.TdEditField.Value = 0.08;
                app.TpEditField.Value = 0.01;
                app.NASTAWYLabel.Visible = true;
                app.Label.Visible = true;
                app.Label.Text = "na wejściu skok jednostkowy";
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                app.ONLINELamp.Enable = true;
                online = 0;
                while app.Switch.Value == "On"
                    k = 6;
                    T_1 = 5.9;
                    T_2 = 0.8;
                    s = tf('s');
                    G_s = k/((1+s*T_1)*(1+s*T_2));
                    kp = 1.6;
                    Ti = 6;
                    Td = 0.08;
                    Tp = 0.01;
                    t = 0:0.01:0.99+online;
                    N = 100+(online*100);
                    online = online + 1;
                    w_1 = ones(1, N);
                    w = w_1.*app.zadanaEditField.Value;
                    y = zeros(1, N);
                    e = zeros(1, N);
                    u = zeros(1, N);
                    e(1) = 1;
                    e(2) = 1;
                    sumaUchybow = e(1) + e(2);
                    for i=3:N
                        e(i) = w(i) - y(i-1);
                        sumaUchybow = sumaUchybow + e(i);
                        u(i) = kp*((e(i) + Tp/Ti*sumaUchybow + Td/Tp*(e(i)-e(i-1))));
                        y(i) = (2-1.42*Tp)*y(i-1) + (-0.21*Tp^2+1.42*Tp-1)*y(i-2) + 1.27* Tp^2*u(i-2);
                    end
                    hold(app.UIAxes5, "on");
                    plot(app.UIAxes5, t, y, 'b');
                    plot(app.UIAxes5, t, w, 'r');
                    legend(app.UIAxes5, 'y', 'w');
                    plot(app.UIAxes6, t, u);
                    legend(app.UIAxes6, 'u'); 
                    pause(1);
                end
            else 
                app.ONLINELamp.Enable = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sygnał wyjściowy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Uchyb')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Sygnał sterujący')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Wskaźniki jakości')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.kpEditFieldLabel = uilabel(app.UIFigure);
            app.kpEditFieldLabel.Text = 'kp';
            app.kpEditField = uieditfield(app.UIFigure, 'numeric');
            app.kpEditField.Value = 1.6;
            app.TiEditFieldLabel = uilabel(app.UIFigure);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiEditField.Value = 6;
            app.TdEditFieldLabel = uilabel(app.UIFigure);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.UIFigure, 'numeric');
            app.TdEditField.Value = 0.08;
            app.TpEditFieldLabel = uilabel(app.UIFigure);
            app.TpEditFieldLabel.Text = 'Tp';
            app.TpEditField = uieditfield(app.UIFigure, 'numeric');
            app.TpEditField.Value = 0.01;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.GEditFieldLabel = uilabel(app.UIFigure);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.UIFigure, 'numeric');
            app.GEditField.ValueChangedFcn = createCallbackFcn(app, @GEditFieldValueChanged, true);
            app.DEditFieldLabel = uilabel(app.UIFigure);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.UIFigure, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.LEditField_2Label = uilabel(app.UIFigure);
            app.LEditField_2Label.Text = 'L';
            app.LEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LEditField_2.ValueChangedFcn = createCallbackFcn(app, @LEditField_2ValueChanged, true);
            app.PEditField_2Label = uilabel(app.UIFigure);
            app.PEditField_2Label.Text = 'P';
            app.PEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PEditField_2.ValueChangedFcn = createCallbackFcn(app, @PEditField_2ValueChanged, true);
            app.GEditField_2Label = uilabel(app.UIFigure);
            app.GEditField_2Label.Text = 'G';
            app.GEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.GEditField_2.ValueChangedFcn = createCallbackFcn(app, @GEditField_2ValueChanged, true);
            app.DEditField_2Label = uilabel(app.UIFigure);
            app.DEditField_2Label.Text = 'D';
            app.DEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_2.ValueChangedFcn = createCallbackFcn(app, @DEditField_2ValueChanged, true);
            app.LEditField_3Label = uilabel(app.UIFigure);
            app.LEditField_3Label.Text = 'L';
            app.LEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.LEditField_3.ValueChangedFcn = createCallbackFcn(app, @LEditField_3ValueChanged, true);
            app.PEditField_3Label = uilabel(app.UIFigure);
            app.PEditField_3Label.Text = 'P';
            app.PEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PEditField_3.ValueChangedFcn = createCallbackFcn(app, @PEditField_3ValueChanged, true);
            app.GEditField_3Label = uilabel(app.UIFigure);
            app.GEditField_3Label.Text = 'G';
            app.GEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.GEditField_3.ValueChangedFcn = createCallbackFcn(app, @GEditField_3ValueChanged, true);
            app.DEditField_3Label = uilabel(app.UIFigure);
            app.DEditField_3Label.Text = 'D';
            app.DEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_3.ValueChangedFcn = createCallbackFcn(app, @DEditField_3ValueChanged, true);
            app.LEditField_4Label = uilabel(app.UIFigure);
            app.LEditField_4Label.Text = 'L';
            app.LEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.LEditField_4.ValueChangedFcn = createCallbackFcn(app, @LEditField_4ValueChanged, true);
            app.PEditField_4Label = uilabel(app.UIFigure);
            app.PEditField_4Label.Text = 'P';
            app.PEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.PEditField_4.ValueChangedFcn = createCallbackFcn(app, @PEditField_4ValueChanged, true);
            app.GEditField_4Label = uilabel(app.UIFigure);
            app.GEditField_4Label.Text = 'G';
            app.GEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.GEditField_4.ValueChangedFcn = createCallbackFcn(app, @GEditField_4ValueChanged, true);
            app.DEditField_4Label = uilabel(app.UIFigure);
            app.DEditField_4Label.Text = 'D';
            app.DEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.DEditField_4.ValueChangedFcn = createCallbackFcn(app, @DEditField_4ValueChanged, true);
            app.nieograniczoneButton = uibutton(app.UIFigure, 'push');
            app.nieograniczoneButton.ButtonPushedFcn = createCallbackFcn(app, @nieograniczoneButtonPushed, true);
            app.nieograniczoneButton.Text = 'nieograniczone';
            app.ograniczoneButton = uibutton(app.UIFigure, 'push');
            app.ograniczoneButton.ButtonPushedFcn = createCallbackFcn(app, @ograniczoneButtonPushed, true);
            app.ograniczoneButton.Text = 'ograniczone';
            app.STEROWANIELabel = uilabel(app.UIFigure);
            app.STEROWANIELabel.Text = 'STEROWANIE';
            app.suwakSliderLabel = uilabel(app.UIFigure);
            app.suwakSliderLabel.Text = 'suwak';
            app.suwakSlider = uislider(app.UIFigure);
            app.suwakSlider.Limits = [0 10];
            app.suwakSlider.ValueChangedFcn = createCallbackFcn(app, @suwakSliderValueChanged, true);
            app.TrybDropDownLabel = uilabel(app.UIFigure);
            app.TrybDropDownLabel.Text = 'Tryb';
            app.TrybDropDown = uidropdown(app.UIFigure);
            app.TrybDropDown.Items = {'samodzielnie', 'modułu', 'symetrii', 'ogr. z AWC', 'wyprzedzające'};
            app.TrybDropDown.ValueChangedFcn = createCallbackFcn(app, @TrybDropDownValueChanged, true);
            app.TrybDropDown.Value = 'samodzielnie';
            app.NASTAWYLabel = uilabel(app.UIFigure);
            app.NASTAWYLabel.Text = '<--- NASTAWY';
            app.Label = uilabel(app.UIFigure);
            app.ONLINELampLabel = uilabel(app.UIFigure);
            app.ONLINELampLabel.Text = 'ONLINE';
            app.ONLINELamp = uilamp(app.UIFigure);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.zadanaEditFieldLabel = uilabel(app.UIFigure);
            app.zadanaEditFieldLabel.Text = 'zadana';
            app.zadanaEditField = uieditfield(app.UIFigure, 'numeric');
            app.zadanaEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end